classdef Thermometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermometerUIFigure            matlab.ui.Figure
        Graphic                        matlab.ui.control.UIAxes
        Label_CurrentAvailableSerials  matlab.ui.control.Label
        CurrentAvailableSerial         matlab.ui.control.DropDown
        RefreshAvailableSerial         matlab.ui.control.Button
        Draw                           matlab.ui.control.Button
        SaveData                       matlab.ui.control.Button
        Label_CurrentTemperature       matlab.ui.control.Label
        Label_BaudRate                 matlab.ui.control.Label
        Label_StopBits                 matlab.ui.control.Label
        Label_DataBits                 matlab.ui.control.Label
        Label_Parity                   matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        BaudRate                       matlab.ui.control.DropDown
        StopBits                       matlab.ui.control.DropDown
        DataBits                       matlab.ui.control.DropDown
        Parity                         matlab.ui.control.DropDown
        Label_Terminator               matlab.ui.control.Label
        Terminator                     matlab.ui.control.DropDown
        CurrentTemperature             matlab.ui.control.Label
        RecordData                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        S % Serialport
        flag % record
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.CurrentAvailableSerial.Items = serialportlist("available");
            if isempty(app.CurrentAvailableSerial.Items)
                app.CurrentAvailableSerial.Items = "无可用串口";
                app.CurrentAvailableSerial.Value = "无可用串口";
            else
                app.S = serialport(app.CurrentAvailableSerial.Value,115200);
            end
            app.UITable.BackgroundColor = [0.15 0.15 0.15];
            app.UITable.ColumnName = ["Temperature"];
            app.UITable.ColumnEditable = [false];
            app.UITable.RowName = "numbered";
        end

        % Button pushed function: RefreshAvailableSerial
        function RefreshAvailableSerialPushed(app, event)
            app.CurrentAvailableSerial.Items = serialportlist("available");
            if isempty(app.CurrentAvailableSerial.Items)
                app.CurrentAvailableSerial.Items = "无可用串口";
                app.CurrentAvailableSerial.Value = "无可用串口";
            else
                app.S = serialport(app.CurrentAvailableSerial.Value,115200);
            end
        end

        % Value changed function: CurrentAvailableSerial
        function CurrentAvailableSerialValueChanged(app, event)
            port = app.CurrentAvailableSerial.Value;
            if port ~= "无可用串口"
                app.S = serialport(port,115200);
            end
%             app.BaudRate.Value = num2str(app.S.BaudRate);
%             app.StopBits.Value = app.S.StopBits;
%             app.DataBits.Value = app.S.DataBits;
%             app.Parity.Value   = app.S.Parity;
%             app.Terminator.Value = app.S.Terminator;
        end

        % Button pushed function: Draw
        function DrawButtonPushed(app, event)
            port = app.CurrentAvailableSerial.Value;
            if port == "无可用串口"
                msgbox("无可用串口，无法绘制","警告");
                return;
            end
           
            table_data = [];
            plot_data = [];
            avg = 0;
            iter = 10;
            i = 0;
            app.flag = false;
            
            while(1)
                recv = read(app.S,10,"string");
                data = str2double(recv);
                
                i = i + 1;
                
                app.CurrentTemperature.Text = recv;
                avg = avg + data/iter;
                
                plot_data = [plot_data data];
                plot(app.Graphic, plot_data);
                
                if i == iter 
                    if  app.flag
                        table_data = app.UITable.Data;
                        app.UITable.Data = [table_data; avg];
                        app.flag = false;
                    end
                    i = 0;
                    avg = 0;
                end
            end
        end

        % Value changed function: BaudRate
        function BaudRateValueChanged(app, event)
            value = app.BaudRate.Value;
            switch value
                case "1382400"
                    app.S.BaudRate = 1382400;
                case "921600"
                    app.S.BaudRate = 921600;
                case "460800"
                    app.S.BaudRate = 460800;
                case "256000"
                    app.S.BaudRate = 256000;
                case "230400"
                    app.S.BaudRate = 230400;
                case "128000"
                    app.S.BaudRate = 128000;
                case "115200"
                    app.S.BaudRate = 115200;
                case "76800"
                    app.S.BaudRate = 76800;
                case "57600"
                    app.S.BaudRate = 57600;
                case "43000"
                    app.S.BaudRate = 43000;
                case "38400"
                    app.S.BaudRate = 38400;
                case "19200"
                    app.S.BaudRate = 19200;
                case "14400"
                    app.S.BaudRate = 14400;
                case "9600"
                    app.S.BaudRate = 9600;
                case "4800"
                    app.S.BaudRate = 4800;
                case "2400"
                    app.S.BaudRate = 2400;
                case "1200"
                    app.S.BaudRate = 1200;
            end
        end

        % Value changed function: StopBits
        function StopBitsValueChanged(app, event)
            value = app.StopBits.Value;
            switch value
                case "1"
                    app.S.StopBits = 1;
                case "1.5"
                    app.S.StopBits = 1.5;
                case "2"
                    app.S.StopBits = 2;
            end
        end

        % Value changed function: DataBits
        function DataBitsValueChanged(app, event)
            value = app.DataBits.Value;
            switch value
                case "8"
                    app.S.DataBits = 8;
                case "7"
                    app.S.DataBits = 7;
                case "6"
                    app.S.DataBits = 6;
                case "5"
                    app.S.DataBits = 5;
            end
        end

        % Value changed function: Parity
        function ParityValueChanged(app, event)
            value = app.Parity.Value;
            switch value
                case "无"
                    app.S.Parity = "none";
                case "奇校验"
                    app.S.Parity = "odd";
                case "偶校验"
                    app.S.Parity = "even";
            end
        end

        % Value changed function: Terminator
        function TerminatorValueChanged(app, event)
            app.S.Terminator = app.Terminator.Value;
        end

        % Button pushed function: SaveData
        function SaveDataButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.xlsx', '选择保存位置', strcat("default", '.xlsx'));
            if isequal(filename, 0) || isequal(filepath, 0)
                errordlg("无效的路径或文件名，无法保存！", "保存出错了");
            else
                fullpath = strcat(filepath, filename);
                writematrix(app.UITable.Data, fullpath);
                msgbox("data saved at %s",fullpath);
            end
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Button pushed function: RecordData
        function RecordDataButtonPushed(app, event)
            app.flag = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermometerUIFigure = uifigure('Visible', 'off');
            app.ThermometerUIFigure.Name = 'Thermometer';
            app.Graphic = uiaxes(app.ThermometerUIFigure);
            title(app.Graphic, '温度-时间函数')
            xlabel(app.Graphic, '时间(100ms)')
            ylabel(app.Graphic, '温度(℃)')
            app.Label_CurrentAvailableSerials = uilabel(app.ThermometerUIFigure);
            app.Label_CurrentAvailableSerials.Text = '当前串口';
            app.CurrentAvailableSerial = uidropdown(app.ThermometerUIFigure);
            app.CurrentAvailableSerial.Items = {};
            app.CurrentAvailableSerial.ValueChangedFcn = createCallbackFcn(app, @CurrentAvailableSerialValueChanged, true);
            app.CurrentAvailableSerial.Value = {};
            app.RefreshAvailableSerial = uibutton(app.ThermometerUIFigure, 'push');
            app.RefreshAvailableSerial.ButtonPushedFcn = createCallbackFcn(app, @RefreshAvailableSerialPushed, true);
            app.RefreshAvailableSerial.Text = '刷新可用串口';
            app.Draw = uibutton(app.ThermometerUIFigure, 'push');
            app.Draw.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.Draw.Text = '绘制曲线';
            app.SaveData = uibutton(app.ThermometerUIFigure, 'push');
            app.SaveData.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveData.Text = '保存数据';
            app.Label_CurrentTemperature = uilabel(app.ThermometerUIFigure);
            app.Label_CurrentTemperature.Text = '当前温度';
            app.Label_BaudRate = uilabel(app.ThermometerUIFigure);
            app.Label_BaudRate.Text = '波特率';
            app.Label_StopBits = uilabel(app.ThermometerUIFigure);
            app.Label_StopBits.Text = '停止位';
            app.Label_DataBits = uilabel(app.ThermometerUIFigure);
            app.Label_DataBits.Text = '数据位';
            app.Label_Parity = uilabel(app.ThermometerUIFigure);
            app.Label_Parity.Text = '奇偶校验';
            app.UITable = uitable(app.ThermometerUIFigure);
            app.UITable.ColumnName = {'ADC_out'; 'Uadc'; 'Temperature'};
            app.UITable.RowName = {};
            app.BaudRate = uidropdown(app.ThermometerUIFigure);
            app.BaudRate.Items = {'1382400', '921600', '460800', '256000', '230400', '128000', '115200', '76800', '57600', '43000', '38400', '19200', '14400', '9600', '4800', '2400', '1200'};
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @BaudRateValueChanged, true);
            app.BaudRate.Value = '115200';
            app.StopBits = uidropdown(app.ThermometerUIFigure);
            app.StopBits.Items = {'1', '1.5', '2'};
            app.StopBits.ValueChangedFcn = createCallbackFcn(app, @StopBitsValueChanged, true);
            app.StopBits.Value = '1';
            app.DataBits = uidropdown(app.ThermometerUIFigure);
            app.DataBits.Items = {'8', '7', '6', '5'};
            app.DataBits.ValueChangedFcn = createCallbackFcn(app, @DataBitsValueChanged, true);
            app.DataBits.Value = '8';
            app.Parity = uidropdown(app.ThermometerUIFigure);
            app.Parity.Items = {'无', '奇校验', '偶校验'};
            app.Parity.ValueChangedFcn = createCallbackFcn(app, @ParityValueChanged, true);
            app.Parity.Value = '无';
            app.Label_Terminator = uilabel(app.ThermometerUIFigure);
            app.Label_Terminator.Text = '终止符';
            app.Terminator = uidropdown(app.ThermometerUIFigure);
            app.Terminator.Items = {'CR', 'CR/LF', 'LF'};
            app.Terminator.ValueChangedFcn = createCallbackFcn(app, @TerminatorValueChanged, true);
            app.Terminator.Value = 'CR/LF';
            app.CurrentTemperature = uilabel(app.ThermometerUIFigure);
            app.CurrentTemperature.Text = '';
            app.RecordData = uibutton(app.ThermometerUIFigure, 'push');
            app.RecordData.ButtonPushedFcn = createCallbackFcn(app, @RecordDataButtonPushed, true);
            app.RecordData.Text = '记录数据';
            % Show the figure after all components are created
            app.ThermometerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermometer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermometerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermometerUIFigure)
        end
    end
end